classdef Q4_echo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpenAudioFileButton      matlab.ui.control.Button
        NEditFieldLabel          matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        MEditFieldLabel          matlab.ui.control.Label
        MEditField               matlab.ui.control.NumericEditField
        alphaEditFieldLabel      matlab.ui.control.Label
        alphaEditField           matlab.ui.control.NumericEditField
        ButtonGroup              matlab.ui.container.ButtonGroup
        TimeDomainButton         matlab.ui.control.RadioButton
        FrequancyDomainButton    matlab.ui.control.RadioButton
        ButtonGroup_2            matlab.ui.container.ButtonGroup
        TimeDomainButton_2       matlab.ui.control.RadioButton
        FrequancyDomainButton_2  matlab.ui.control.RadioButton
        echoButton               matlab.ui.control.Button
        InputAudioButton         matlab.ui.control.Button
        EchoAudioButton          matlab.ui.control.Button
        PlayAudioFileLabel       matlab.ui.control.Label
        SaveEchoedVoiceLabel     matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        OpenAudioFileLabel       matlab.ui.control.Label
        EchoParametersLabel      matlab.ui.control.Label
        AudiosignalLabel         matlab.ui.control.Label
        AudioSignalwithTwoSidedEchosLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioFile = 'HW1_Q4_voice2.wav' % Description
        path = '';      % Default audio path
        flag1 = 0;
        flag2 = 0;
        N = 3;
        M = 44100;
        alpha = 0.7;
        Voice;
        Fs;
        output;
        
    end
    
    methods (Access = private)
        

        function audioAdded(app)
            [app.audioFile,app.path] = uigetfile('*.wav');
            [app.Voice , app.Fs] = audioread([app.path app.audioFile]) ;
            OriginalFile(app);
            

        end
        
        
        function OriginalFile(app)
            L = length(app.Voice);
            slength = L/app.Fs;
            t = (linspace(0, slength, L))';
            freq_domain = fftshift(fft(app.Voice));
            freq_L = length(freq_domain);
            freq_grid = (-freq_L/2 : freq_L/2-1) / (freq_L/app.Fs);
            switch app.ButtonGroup.SelectedObject.Text 
                case "Time Domain"
                    app.UIAxes.XLabel.String = "Time(s)";
                    app.UIAxes.YLabel.String = "Amplitude";
                    plot(app.UIAxes,t,app.Voice);  
                case "Frequancy Domain"
                    app.UIAxes.XLabel.String = "Freqency(Hz)";
                    app.UIAxes.YLabel.String = "Magnitude";
                    plot(app.UIAxes,freq_grid,abs(freq_domain));
            end
            
            if(app.flag1 == 1)
                sound(app.Voice,app.Fs);
                app.flag1 =0;
            end
            
            
        end
        
        
        function echoFile(app)
            app.N = app.NEditField.Value;
            app.M = app.MEditField.Value;
            app.alpha = app.alphaEditField.Value;
            voice_echo = zeros(1, length(app.Voice)+app.N*app.M);
            for i = 1 : length(app.Voice)
                for k = 0 : app.N
                    voice_echo(i+k*app.M) = voice_echo(i+k*app.M) + ((app.alpha)^k)*app.Voice(i);

                end
            end
            L2 = length(voice_echo);
            slength2 = L2/app.Fs;
            t2 = (linspace(0, slength2, L2))';
            freq_domain2 = fftshift(fft(voice_echo));
            freq_L2 = length(freq_domain2);
            freq_grid2 = (-freq_L2/2 : freq_L2/2-1) / (freq_L2/app.Fs);
            
            switch app.ButtonGroup_2.SelectedObject.Text 
                case "Time Domain"
                    app.UIAxes2.XLabel.String = "Time(s)";
                    app.UIAxes2.YLabel.String = "Amplitude";
                    plot(app.UIAxes2,t2,voice_echo);  
                case "Frequancy Domain"
                    app.UIAxes2.XLabel.String = "Freqency(Hz)";
                    app.UIAxes2.YLabel.String = "Magnitude";
                    plot(app.UIAxes2,freq_grid2,abs(freq_domain2));
            end
            
            app.output = voice_echo;
            if(app.flag2 == 1)
                sound(voice_echo,app.Fs);
                app.flag2 =0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            audioAdded(app);    % Reference to audioAdded Function
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            echoFile(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            echoFile(app);
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            echoFile(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            OriginalFile(app);
        end

        % Button pushed function: echoButton
        function echoButtonPushed(app, event)
            echoFile(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            echoFile(app);
        end

        % Button pushed function: InputAudioButton
        function InputAudioButtonPushed(app, event)
            app.flag1 = 1;
            OriginalFile(app);
        end

        % Button pushed function: EchoAudioButton
        function EchoAudioButtonPushed(app, event)
            app.flag2 = 1;
            echoFile(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('EchoedAudio.wav',app.output,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenAudioFileButton = uibutton(app.UIFigure, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 3;
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 44100;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 0.7;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TimeDomainButton = uiradiobutton(app.ButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FrequancyDomainButton = uiradiobutton(app.ButtonGroup);
            app.FrequancyDomainButton.Text = 'Frequancy Domain';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.TimeDomainButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.TimeDomainButton_2.Text = 'Time Domain';
            app.TimeDomainButton_2.Value = true;
            app.FrequancyDomainButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.FrequancyDomainButton_2.Text = 'Frequancy Domain';
            app.echoButton = uibutton(app.UIFigure, 'push');
            app.echoButton.ButtonPushedFcn = createCallbackFcn(app, @echoButtonPushed, true);
            app.echoButton.Text = 'echo';
            app.InputAudioButton = uibutton(app.UIFigure, 'push');
            app.InputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputAudioButtonPushed, true);
            app.InputAudioButton.Text = 'Input Audio';
            app.EchoAudioButton = uibutton(app.UIFigure, 'push');
            app.EchoAudioButton.ButtonPushedFcn = createCallbackFcn(app, @EchoAudioButtonPushed, true);
            app.EchoAudioButton.Text = 'Echo Audio';
            app.PlayAudioFileLabel = uilabel(app.UIFigure);
            app.PlayAudioFileLabel.Text = 'Play Audio File';
            app.SaveEchoedVoiceLabel = uilabel(app.UIFigure);
            app.SaveEchoedVoiceLabel.Text = 'Save Echoed Voice';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenAudioFileLabel = uilabel(app.UIFigure);
            app.OpenAudioFileLabel.Text = 'Open Audio File';
            app.EchoParametersLabel = uilabel(app.UIFigure);
            app.EchoParametersLabel.Text = 'Echo Parameters';
            app.AudiosignalLabel = uilabel(app.UIFigure);
            app.AudiosignalLabel.Text = 'Audio signal';
            app.AudioSignalwithTwoSidedEchosLabel = uilabel(app.UIFigure);
            app.AudioSignalwithTwoSidedEchosLabel.Text = 'Audio Signal with Two-Sided Echos';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Audio Signal with Two-Sided Echos')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q4_echo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end