classdef Q5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AddPanel                 matlab.ui.container.Panel
        RealEditFieldLabel       matlab.ui.control.Label
        RealEditField            matlab.ui.control.NumericEditField
        ImaginaryEditFieldLabel  matlab.ui.control.Label
        ImaginaryEditField       matlab.ui.control.NumericEditField
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        AddButton                matlab.ui.control.Button
        InformationPanel         matlab.ui.container.Panel
        NumofPolesLabel          matlab.ui.control.Label
        NumofZerosLabel          matlab.ui.control.Label
        PlotSettingButtonGroup   matlab.ui.container.ButtonGroup
        MagnitudelinearButton    matlab.ui.control.RadioButton
        MagnitudedBButton        matlab.ui.control.RadioButton
        PhaseargButton           matlab.ui.control.RadioButton
        PhaseARGButton           matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NOfP = 0; % Num. of Poles
        NOfZ = 0; % Num. of Zeros
        flag1 = 0;
        a = [1];
        b = [1];
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            real = app.RealEditField.Value;
            img = app.ImaginaryEditField.Value;
            n = [1 -(real+1j*img)];
            switch app.TypeDropDown.Value
                case "Pole"
                    app.NOfP = app.NOfP + 1 ;
                    app.a = conv(app.a, n);
                    
                case "Zero"
                    app.NOfZ = app.NOfZ + 1 ;
                    app.b = conv(app.b, n);
            end
            n1 = sprintf('%d',app.NOfP);
            n2 = sprintf('%d',app.NOfZ);
            app.NumofPolesLabel.Text = ['Num of Poles :   ',n1];
            app.NumofZerosLabel.Text = ['Num of Zeros :   ',n2];  
            PlotSettingButtonGroupSelectionChanged(app,1);
        end

        % Selection changed function: PlotSettingButtonGroup
        function PlotSettingButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotSettingButtonGroup.SelectedObject.Text;
            W = linspace(-pi,pi,1024);
            H = freqz(app.b, app.a, W);
            
            switch selectedButton
                case "Magnitude (linear)"
                    plot(app.UIAxes,W/pi, abs(H));
                    title(app.UIAxes,('Frequency Responce(Mag. Linear'));
                    grid(app.UIAxes,'minor');
                    ylabel(app.UIAxes,'Magnitude(Linear)');
                    
                case "Magnitude (dB)"
                    plot(app.UIAxes,W/pi,20*log10(abs(H)));
                    title(app.UIAxes,('Frequency Responce(Mag. dB)'));
                    grid(app.UIAxes,'minor');
                    ylabel(app.UIAxes,'Magnitude(dB)');
                    
                case "Phase (arg)"
                    plot(app.UIAxes,W/pi,phase(H)*180/pi); 
                    title(app.UIAxes,('Frequency Responce(Phase unwrapped)'));
                    grid(app.UIAxes,'minor');
                    ylabel(app.UIAxes,'Phase(arg)');
                    
                case "Phase (ARG)"
                    plot(app.UIAxes,W/pi,angle(H)*180/pi); 
                    title(app.UIAxes,('Frequency Responce(Phase wrapped)'));
                    grid(app.UIAxes,'minor');
                    ylabel(app.UIAxes,'Phase(ARG)');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddPanel = uipanel(app.UIFigure);
            app.AddPanel.Title = 'Add';
            app.RealEditFieldLabel = uilabel(app.AddPanel);
            app.RealEditFieldLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.AddPanel, 'numeric');
            app.ImaginaryEditFieldLabel = uilabel(app.AddPanel);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.AddPanel, 'numeric');
            app.TypeDropDownLabel = uilabel(app.AddPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddPanel);
            app.TypeDropDown.Items = {'Pole', 'Zero'};
            app.TypeDropDown.Value = 'Pole';
            app.AddButton = uibutton(app.AddPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.NumofPolesLabel = uilabel(app.InformationPanel);
            app.NumofPolesLabel.Text = 'Num. of Poles:';
            app.NumofZerosLabel = uilabel(app.InformationPanel);
            app.NumofZerosLabel.Text = 'Num. of Zeros:';
            app.PlotSettingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotSettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotSettingButtonGroupSelectionChanged, true);
            app.PlotSettingButtonGroup.Title = 'Plot Setting';
            app.MagnitudelinearButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudelinearButton.Text = 'Magnitude (linear)';
            app.MagnitudelinearButton.Value = true;
            app.MagnitudedBButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.MagnitudedBButton.Text = 'Magnitude (dB)';
            app.PhaseargButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseargButton.Text = 'Phase (arg)';
            app.PhaseARGButton = uiradiobutton(app.PlotSettingButtonGroup);
            app.PhaseARGButton.Text = 'Phase (ARG)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Responce')
            xlabel(app.UIAxes, 'Normalizes Frequency( *pi  rad/sample)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end