classdef Q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Sincn2N2Panel               matlab.ui.container.Panel
        NEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        UpsamplingPanel             matlab.ui.container.Panel
        LEditFieldLabel             matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        FilterCutoffFrequencyPanel  matlab.ui.container.Panel
        wEditFieldLabel             matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        DownsamplingPanel           matlab.ui.container.Panel
        MEditField_2Label           matlab.ui.control.Label
        MEditField_2                matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        N = 3; 
        L = 1; 
        w = 0;
        M = 1;
        y_filter;
        b ;
        x_n;
        y_n;
        lin = 0;

    end
    
    methods (Access = private)

        function change(app)
            m = 300;
            n = 0:1:m-1;
            app.x_n = (sinc((n-m/2)/(2*app.N))).^2;
            app.x_n = upsample(app.x_n,app.L);
            app.lin = linspace(-3*pi,3*pi,app.L*1024/app.M);
            if(app.w > 0 )
                app.b = fir1(48,app.w);
%                 app.x_n = filter(app.b,1,app.x_n);
                app.x_n = conv(app.x_n,app.b);
                app.y_filter = freqz(app.b, 1, app.lin); 
            end
            app.x_n = downsample(app.x_n,app.M);
            app.y_n = freqz(app.x_n, 1, app.lin);
            if(app.w>0)
                app.y_n = app.y_filter .* app.y_n;
            end
            plot(app.UIAxes,app.lin/pi,abs(app.y_n)); 
            xlim(app.UIAxes,[-3 3]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            m = 300;
            n = 0:1:m-1;
            app.x_n = (sinc((n-m/2)/(2*app.N))).^2;
            app.lin = linspace(-3*pi,3*pi,1024);
            app.y_n = freqz(app.x_n, 1, app.lin);
            plot(app.UIAxes,app.lin/pi,abs(app.y_n)); 
            xlim(app.UIAxes,[-3 3]);
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.N = app.NEditField.Value;
            change(app);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
            change(app);
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            app.w = app.wEditField.Value;
            change(app);
        end

        % Value changed function: MEditField_2
        function MEditField_2ValueChanged(app, event)
            app.M = app.MEditField_2.Value;
            change(app);
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Sincn2N2Panel = uipanel(app.UIFigure);
            app.Sincn2N2Panel.Title = 'Sinc(n/(2N)).^2';
            app.NEditFieldLabel = uilabel(app.Sincn2N2Panel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Sincn2N2Panel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 3;
            app.UpsamplingPanel = uipanel(app.UIFigure);
            app.UpsamplingPanel.Title = 'Upsampling';
            app.LEditFieldLabel = uilabel(app.UpsamplingPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UpsamplingPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.FilterCutoffFrequencyPanel = uipanel(app.UIFigure);
            app.FilterCutoffFrequencyPanel.Title = 'Filter Cut-off Frequency';
            app.wEditFieldLabel = uilabel(app.FilterCutoffFrequencyPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.FilterCutoffFrequencyPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.DownsamplingPanel = uipanel(app.UIFigure);
            app.DownsamplingPanel.Title = 'Downsampling';
            app.MEditField_2Label = uilabel(app.DownsamplingPanel);
            app.MEditField_2Label.Text = 'M';
            app.MEditField_2 = uieditfield(app.DownsamplingPanel, 'numeric');
            app.MEditField_2.ValueChangedFcn = createCallbackFcn(app, @MEditField_2ValueChanged, true);
            app.MEditField_2.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Responce')
            xlabel(app.UIAxes, 'Normalized Frequency( * pi rad/sample)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end